classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        toolGrid                       matlab.ui.container.GridLayout
        tool_nRowsGrid                 matlab.ui.container.GridLayout
        tool_resetButton               matlab.ui.control.Image
        tool_nRowsIcon                 matlab.ui.control.Image
        tool_nRows                     matlab.ui.control.Label
        tool_SelectedEmision           matlab.ui.control.Label
        tool_Serarator2                matlab.ui.control.Image
        tool_mapPlotSize               matlab.ui.control.Slider
        tool_DensityButton             matlab.ui.control.Image
        tool_ScatterButton             matlab.ui.control.Image
        tool_Serarator1                matlab.ui.control.Image
        tool_ExportButton              matlab.ui.control.Button
        Document                       matlab.ui.container.GridLayout
        axesPanel                      matlab.ui.container.Panel
        LeftPanelVisibility            matlab.ui.control.Image
        LeftPanel                      matlab.ui.container.Panel
        LeftPanelGrid                  matlab.ui.container.GridLayout
        Tab4_Panel                     matlab.ui.container.GridLayout
        layout_cartesianPlotPanel      matlab.ui.container.Panel
        layout_cartesianPlotGrid       matlab.ui.container.GridLayout
        layout_cartesianPlotSubPanel   matlab.ui.container.Panel
        layout_cartesianPlotSubGrid    matlab.ui.container.GridLayout
        chPowerSource_smoothedPanel    matlab.ui.container.Panel
        Smoothed_Grid                  matlab.ui.container.GridLayout
        Smoothed_Factor                matlab.ui.control.Spinner
        Smoothed_FactorLabel           matlab.ui.control.Label
        Smoothed_Type                  matlab.ui.control.DropDown
        Smoothed_TypeLabel             matlab.ui.control.Label
        chPowerSource_smoothed         matlab.ui.control.CheckBox
        chPowerSource_binTable         matlab.ui.control.CheckBox
        chPowerSource_rawTable         matlab.ui.control.CheckBox
        layout_cartesianPlotLabel      matlab.ui.control.Label
        layout_cartesianPlotTitle      matlab.ui.control.Label
        layout_mapPlotPanel            matlab.ui.container.Panel
        layout_mapPlotGrid             matlab.ui.container.GridLayout
        mapPlot_Panel11                matlab.ui.container.Panel
        mapPlot_Grid11                 matlab.ui.container.GridLayout
        mapPlot_Property1Panel_2       matlab.ui.container.Panel
        mapPlot_Property1Grid_2        matlab.ui.container.GridLayout
        mapPlot_size1_2                matlab.ui.control.Slider
        mapPlot_color1_2               matlab.ui.control.Button
        mapPlot_Type_2                 matlab.ui.control.DropDown
        imgRoute_TypeLabel_3           matlab.ui.control.Label
        Colormap                       matlab.ui.control.DropDown
        ColormapLabel                  matlab.ui.control.Label
        mapPlot_Property1Panel         matlab.ui.container.Panel
        mapPlot_Property1Grid          matlab.ui.container.GridLayout
        mapPlot_size1                  matlab.ui.control.Slider
        mapPlot_color1                 matlab.ui.control.Button
        mapPlot_Type                   matlab.ui.control.DropDown
        imgRoute_TypeLabel_2           matlab.ui.control.Label
        imgRoute_Panel                 matlab.ui.container.Panel
        imgRoute_Grid                  matlab.ui.container.GridLayout
        imgRoute_Size                  matlab.ui.control.Slider
        imgRoute_Color2                matlab.ui.control.Button
        imgRoute_Color1                matlab.ui.control.Button
        imgRoute_Type                  matlab.ui.control.DropDown
        imgRoute_TypeLabel             matlab.ui.control.Label
        mapPlot_Subtitle11             matlab.ui.control.Label
        layout_mapPlotSourcePanel      matlab.ui.container.ButtonGroup
        layout_mapPlotSource_binData   matlab.ui.control.RadioButton
        layout_mapPlotSource_rawData   matlab.ui.control.RadioButton
        layout_mapPlotSourceLabel      matlab.ui.control.Label
        layout_mapPlotLock             matlab.ui.control.Image
        layout_mapPlotLabel            matlab.ui.control.Label
        Tab3_Panel                     matlab.ui.container.GridLayout
        DatabinningLabel               matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        binTable_Type                  matlab.ui.control.DropDown
        binTable_TypeLabel             matlab.ui.control.Label
        binTable_Fcn                   matlab.ui.control.DropDown
        binTable_FcnLabel              matlab.ui.control.Label
        binTable_Factor                matlab.ui.control.Spinner
        binTable_FactorLabel           matlab.ui.control.Label
        binTable_Grid                  matlab.ui.container.GridLayout
        binTable_Info                  matlab.ui.control.Label
        binTable_Col4                  matlab.ui.control.Label
        binTable_Col3                  matlab.ui.control.Label
        binTable_Col2                  matlab.ui.control.Label
        binTable_Col1                  matlab.ui.control.Label
        binTable_UITable               matlab.ui.control.Table
        Tab2_Panel                     matlab.ui.container.GridLayout
        points_Tree                    matlab.ui.container.CheckBoxTree
        points_addImage                matlab.ui.control.Image
        points_addValuePanel           matlab.ui.container.Panel
        points_addValueGrid            matlab.ui.container.GridLayout
        points_addSubtype2Distance     matlab.ui.control.Spinner
        points_addSubtype2DistanceLabel  matlab.ui.control.Label
        points_addSubtype2NPeaks       matlab.ui.control.Spinner
        points_addSubtype2NPeaksLabel  matlab.ui.control.Label
        points_addSubtype2DropDown     matlab.ui.control.DropDown
        points_addSubtype2Label        matlab.ui.control.Label
        points_addSubtype1Distance     matlab.ui.control.NumericEditField
        points_addSubtype1DistanceLabel  matlab.ui.control.Label
        points_addSubtype1TextArea     matlab.ui.control.TextArea
        points_addSubtype1DropDown     matlab.ui.control.DropDown
        points_addSubtype1Label        matlab.ui.control.Label
        points_addTypePanel            matlab.ui.container.ButtonGroup
        points_addFindPeaks            matlab.ui.control.RadioButton
        points_addRFDataHub            matlab.ui.control.RadioButton
        points_TreeLabel               matlab.ui.control.Label
        Tab1_Panel                     matlab.ui.container.GridLayout
        filter_Tree                    matlab.ui.container.Tree
        filter_addImageGrid            matlab.ui.container.GridLayout
        filter_addImage                matlab.ui.control.Image
        filter_roiOptions              matlab.ui.container.Tree
        levelFilterNode                matlab.ui.container.TreeNode
        levelFilter_thr                matlab.ui.container.TreeNode
        geoFilterNode                  matlab.ui.container.TreeNode
        geoFilter_Circle               matlab.ui.container.TreeNode
        geoFilter_Rectangle            matlab.ui.container.TreeNode
        geoFilter_Polygon              matlab.ui.container.TreeNode
        filter_TreeLabel               matlab.ui.control.Label
        taskMetaData                   matlab.ui.control.TextArea
        emissionsList                  matlab.ui.control.DropDown
        emissionListGrid               matlab.ui.container.GridLayout
        emissionListRefresh            matlab.ui.control.Image
        emissionListLabel              matlab.ui.control.Label
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button4Grid               matlab.ui.container.GridLayout
        menu_Button4Icon               matlab.ui.control.Image
        menu_Button4Label              matlab.ui.control.Label
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menu_Image                     matlab.ui.control.Image
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        points_ContextMenu             matlab.ui.container.ContextMenu
        points_delButton               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        UIAxes1
        UIAxes2

        restoreView = {[0,1], [0,1]}
        
        specData
        Index        

        specTable                                                           % General table (instantaneous channel power)
        filtTable                                                           % Filtered specData
        binTable                                                            % Summarized filtTable (Data Binning Criterium)

        filterTable = table('Size', [0, 4],                                      ...
                            'VariableTypes', {'cell', 'cell', 'cell', 'double'}, ...
                            'VariableNames', {'type', 'subtype', 'handle', 'enable'})
        
        pointsTable = table({}, struct('source', {}, 'idx', {}, 'Coordinates', {}), true(0, 1),...
                            'VariableNames', {'type', 'value', 'visible'})

        hCar
        hScatter
        hDensity
        hTimeStamp
        hTimeStampLabel
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.axesPanel, 5, 1, "Padding", "tight");
            
            % Eixo geográfico (MAPA)
            app.UIAxes1 = geoaxes(t, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater', 'Box', 'off');
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [4 1];
            app.UIAxes1.LatitudeLabel.String  = '';
            app.UIAxes1.LongitudeLabel.String = '';
            colormap(app.UIAxes1, app.Colormap.Value)
            hold(app.UIAxes1, 'on')

            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            % Eixo cartesiano
            app.UIAxes2 = uiaxes(t);
            app.UIAxes2.Layout.Tile = 5;
            xlabel(app.UIAxes2, 'Varredura')
            ylabel(app.UIAxes2, 'Potência canal (dBm)')

            set(app.UIAxes2, Color         = [0 0 0],     ...
                             ClippingStyle = 'rectangle', ...
                             XGrid         = 'on',        ...
                             XMinorGrid    = 'on',        ...
                             YGrid         = 'on',        ...
                             YMinorGrid    = 'on',        ...
                             Interactions  = [],          ...
                             Toolbar       = [],          ...
                             FontSize      = 9,           ...
                             GridColor     = [0.9412 0.9412 0.9412])

            app.UIAxes2.XAxis.Exponent = 0;
            hold(app.UIAxes2, 'on')

            startup_AxesInteractions(app)
            drawnow
        end


        %-----------------------------------------------------------------%
        function startup_AxesInteractions(app)
            % Toolbar
            plotFcn.axesInteraction.GeographicToolbar(app.UIAxes1, 'default', 'export')
            plotFcn.axesInteraction.CartesianToolbar(app.UIAxes2, {'pan', 'restoreview'});

            % Default interactions
            plotFcn.axesInteraction.CartesianDefaultInteractions(app.UIAxes2, [dataTipInteraction, regionZoomInteraction])

            % Interactions callbacks
            plotFcn.axesInteraction.InteractionsCallbacks({'Pan', 'Restore View'}, app.UIAxes2, app)
        end
        

        %-----------------------------------------------------------------%
        function startup_General(app)
            startup_EmissionsList(app)
            startup_GeneralMetaData(app)
            tool_MetaDataInfo(app)
            binning_specTableCreation(app)

            expand(app.filter_roiOptions, 'all')
        end


        %-----------------------------------------------------------------%
        function startup_EmissionsList(app)
            emissionTable = app.specData.UserData.Emissions;

            if isempty(emissionTable)
                error('Tabela de emissões está vazia!')
            end
            
            app.emissionsList.Items = {};
            for ii = 1:height(emissionTable)
                app.emissionsList.Items{end+1} = sprintf('%d: %.3f MHz ⌂ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

            try
                app.emissionsList.Value = app.emissionsList.Items{app.Index(2)};
            catch
            end
        end


        %-----------------------------------------------------------------%
        function startup_GeneralMetaData(app)
            metaData = '';
            for ii = 1:height(app.specData.RelatedFiles)
                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(ii), 'dd/mm/yyyy HH:MM:SS'), ...
                                                     datestr(app.specData.RelatedFiles.EndTime(ii),   'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita médio)\n\n'], ...
                                    metaData,                              ...
                                    app.specData.RelatedFiles.File{ii},    ...
                                    ObservationTime,                       ...
                                    app.specData.RelatedFiles.nSweeps(ii), ...
                                    app.specData.RelatedFiles.RevisitTime(ii));
            end

            app.taskMetaData.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'      ...
                                      'Pontos por traço: %.0f\n'               ...
                                      'Unidade: %s\n'                          ...
                                      'Resolução: %.3f kHz\n\n%s'],            ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.LevelUnit,         ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);
        end


        %-----------------------------------------------------------------%
        function tool_MetaDataInfo(app)
            app.tool_SelectedEmision.Text = sprintf('%s\nID %d: %.3f - %.3f MHz @ %s', app.specData.Receiver,                 ...
                                                                                       app.specData.RelatedFiles.ID(1),       ...
                                                                                       app.specData.MetaData.FreqStart/1e+6,  ...
                                                                                       app.specData.MetaData.FreqStop /1e+6,  ...
                                                                                       extractAfter(app.emissionsList.Value, ': '));
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            if ~isempty(app.filterTable)
                for ii = 1:height(app.filterTable)
                    uitreenode(app.filter_Tree, 'Text', app.filterTable.subtype{ii}, 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                end

                set(app.filter_Tree, 'SelectedNodes', app.filter_Tree.Children(end), 'Enable', 1)
                filter_TreeSelectionChanged(app)

                set(app.filter_ContextMenu.Children, Enable=1)
            else
                app.filter_Tree.Enable = 1;
                set(app.filter_ContextMenu.Children, Enable=0)
            end

            if isempty(find(app.filterTable.subtype == "THRESHOLD", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.filter_roiOptions, s, 'node', app.levelFilter_thr)
        end


        %-----------------------------------------------------------------%
        function filter_updateInfo(app)
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            idx = find(strcmp(app.pointsTable.type, 'FindPeaks'));
            if ~isempty(idx)
                app.pointsTable(idx,:) = [];
                points_TreeBuilding(app)
            end

            binning_geoTables(app)
            binning_UITable(app)
            plot_MainControl(app)

            delete(d)
        end
       

        %-----------------------------------------------------------------%
        function binning_specTableCreation(app)
            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:height(app.specData.RelatedFiles)
                Lat  = [Lat;  app.specData.RelatedFiles.GPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedFiles.GPS{ii}.Matrix(:,2)];
            end
            
            chPower = binning_chPower(app);
            app.specTable = table(timestamp, Lat, Long, chPower);
        end
        
        
        %-----------------------------------------------------------------%
        function binning_geoTables(app)
            binning_filtTable(app)
            binning_binTable(app)
        end
        

        %-----------------------------------------------------------------%
        function binning_filtTable(app)
            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.filterTable.type == "Level");
            idx2 = find(app.filterTable.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.handle{ii}.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.handle{ii}.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não foi obtida nenhuma amostra ao aplicar o filtro. Por essa razão, esse filtro será excluído automaticamente.');
                app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                filter_delFilter(app, struct('Source', app.filter_delButton))

                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            [app.filtTable.LatEq, app.filtTable.LongEq] = grn2eqa(app.filtTable.Lat, app.filtTable.Long);

            app.filtTable.FK1 = find(idy);
            app.filtTable.FK2 = -1*ones(height(app.filtTable), 1, 'int32');

            NN = sum(idy);
            MM = height(app.specTable);
            app.tool_nRows.Text = sprintf('%d de %d varreduras\n%.1f %%', NN, MM, (NN/MM)*100);

            if ~isempty(idx1)
                app.filterTable.handle{idx1}.Position(2) = height(app.filtTable);
            end
        end


        %-----------------------------------------------------------------%
        function binning_binTable(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    [binLat, binLong, binCount] = hista(app.filtTable.Lat, app.filtTable.Long, (app.binTable_Factor.Value/1000)^2);            
                    [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
                    PK2 = int32(1:numel(binLat))';
                    
                    dist = pdist2([app.filtTable.LongEq, app.filtTable.LatEq], [binLongEq, binLatEq]);            
                    [~, app.filtTable.FK2] = min(dist, [], 2);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Lat),  '\n')';
                    lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Long), '\n')';
                    latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));
        
                    [latlong_String, latlong_Index, app.filtTable.FK2] = unique(latlong, 'stable');
                    
                    PK2      = int32(1:numel(latlong_String))';
                    binLat   = round(app.filtTable.Lat(latlong_Index),  app.binTable_Factor.Value);
                    binLong  = round(app.filtTable.Long(latlong_Index), app.binTable_Factor.Value);
                    binCount = accumarray(app.filtTable.FK2, 1);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);
            end
        end


        %-----------------------------------------------------------------%
        function chPower = binning_chPower(app)
            idx = find(strcmp(app.emissionsList.Items, app.emissionsList.Value), 1);

            FreqCenter = app.specData.UserData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.UserData.Emissions.BW(idx)        * 1e+3;

            chPower = fcn.chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);
        end


        %-----------------------------------------------------------------%
        function binPower = binning_binPower(app)
            switch app.binTable_Fcn.Value
                case 'min';    binFcn = @min;
                case 'mean';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'median'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'rms';    binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'max';    binFcn = @max;
            end
            binPower = splitapply(binFcn, app.filtTable.chPower, app.filtTable.FK2);
        end


        %-----------------------------------------------------------------%
        function binning_UITable(app)
            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])            

            % Identificar as distâncias entre pontos adjacentes (ajustados
            % no grid definido pelo número das casas decimais da latitude e
            % longitude)
            locIndex = app.filtTable.FK2(1);
            for ii = 1:height(app.filtTable)
                if locIndex(end) ~= app.filtTable.FK2(ii)
                    locIndex(end+1,1) = app.filtTable.FK2(ii);
                end
            end

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            if numel(locIndex) >= 2
                ptDist = deg2km(distance('gc', [app.binTable.binLat(locIndex(1:end-1)), app.binTable.binLong(locIndex(1:end-1))], ...
                                               [app.binTable.binLat(locIndex(2:end)),   app.binTable.binLong(locIndex(2:end))]));
                [ptDist_min, ptDist_max] = bounds(ptDist*1000);
                ptDist_mean = mean(ptDist)*1000; 
    
                % Sumário:            
                auxString0 = sprintf('%.0f pontos', height(app.binTable));
                auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
                auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);
    
                app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);
            else
                app.binTable_Info.Text = '';
            end
        end


        %-----------------------------------------------------------------%
        function points_TreeBuilding(app)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
            end

            checkedTreeNodes = [];
            for ii = 1:height(app.pointsTable)
                treeNode = uitreenode(app.points_Tree, 'Text', sprintf('%s: %s', app.pointsTable.type{ii}, strjoin(string(app.pointsTable.value(ii).idx), ',')), ...
                                                       'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                if app.pointsTable.visible(ii)
                    checkedTreeNodes = [checkedTreeNodes, treeNode];
                end
            end
            app.points_Tree.CheckedNodes = checkedTreeNodes;
        end


        %-----------------------------------------------------------------%
        function points_TableCheck(app, newRow)
            status = true;
            for ii = 1:height(app.pointsTable)
                if isequal(newRow(1), app.pointsTable{ii,1}) && ...
                   isequal(newRow{2}, app.pointsTable{ii,2})
                    status = false;
                    break
                end
            end

            if status
                app.pointsTable(end+1,:) = newRow;
            else
                error('Registro já incluído!')
            end
        end


        %-----------------------------------------------------------------%
        function [idx, Coordinates] = points_FindPeaks(app, Source, NPeaks, MinDistance)
            idx = [];
            Coordinates = [];

            switch Source
                case 'Dados brutos';                     sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'rawTable'),     'YData');
                case 'Dados processados (Data Binning)'; sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'binTable'),     'YData');
                case 'Curva suave';                      sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), 'YData');
            end

            [~, pksIndex] = findpeaks(sourceData, 'SortStr', 'descend');

            if ~isempty(pksIndex)
                idx = pksIndex(1);

                for ii = 2:numel(pksIndex)
                    if numel(idx) >= NPeaks
                        break
                    end

                    switch Source
                        case {'Dados brutos', 'Curva suave'}
                            refCoordinates = app.filtTable{idx, 1:2};
                            Coordinates    = app.filtTable{pksIndex(ii), 1:2};    
                        
                        case 'Dados processados (Data Binning)'
                            refCoordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                            Coordinates    = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), pksIndex(ii))), 1:2};
                    end
    
                    tempDistance = deg2km(distance(refCoordinates, Coordinates(end,:))); % em km
                    if all(tempDistance >= MinDistance)
                        idx(end+1) = pksIndex(ii);
                    end
                end

                switch Source
                    case {'Dados brutos', 'Curva suave'};    Coordinates = app.filtTable{idx, 1:2};    
                    case 'Dados processados (Data Binning)'; Coordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                end
            end
        end


        %-----------------------------------------------------------------%
        function plot_MainControl(app)
            % app.UIAxes2
            plot_ChannelPower(app)
            drawnow
            
            % app.UIAxes1
            plot_Route(app)
            plot_Density(app)
            plot_Distortion(app)
            plot_Points(app)
            plot_Car(app)
            drawnow

            plot_axesStackingOrder(app)
        end


        %-----------------------------------------------------------------%
        function plot_ChannelPower(app)
            delete(findobj(app.UIAxes2.Children, '-not', 'Tag', 'ROI'))

            colors   = app.CallingApp.General.Colors;
            [~, idx] = min(abs(app.filtTable.FK1-app.CallingApp.timeIndex));

            [minY, maxY] = bounds(app.specTable.chPower);
            set(app.UIAxes2, 'XLim', [1, height(app.filtTable)+.001], 'YLim', [minY, maxY+.001])
            app.restoreView = {app.UIAxes2.XLim, app.UIAxes2.YLim};
                                
            smoothedTable         = app.filtTable;
            smoothedTable.chPower = smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value);

            chPowerLine           = plot(app.UIAxes2, app.filtTable.chPower,                    'Marker', app.mapPlot_Type_2.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', colors(4,:), 'MarkerEdgeColor', colors(4,:), 'Color', colors(4,:), 'Visible', app.chPowerSource_rawTable.Value, 'Tag', 'rawTable');
            binPowerLine          = plot(app.UIAxes2, app.binTable.binPower(app.filtTable.FK2), 'Marker', app.mapPlot_Type_2.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', colors(2,:), 'MarkerEdgeColor', colors(4,:), 'Color', colors(2,:), 'Visible', app.chPowerSource_binTable.Value, 'Tag', 'binTable');
            smoothedLine          = plot(app.UIAxes2, smoothedTable.chPower,                    'Marker', app.mapPlot_Type_2.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', colors(3,:), 'MarkerEdgeColor', colors(4,:), 'Color', colors(3,:), 'Visible', app.chPowerSource_smoothed.Value, 'Tag', 'SmoothedData');

            app.hTimeStamp        = plot(app.UIAxes2, [idx idx], [minY maxY], 'Color', 'red', 'Marker', '.', 'MarkerSize', 12, 'PickableParts', 'none');
            app.hTimeStampLabel   = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.filtTable.chPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on', 'PickableParts', 'none');

            legend(app.UIAxes2, {'Dados brutos', 'Data Binning', 'Curva suave'}, FontSize=7, Color=[.98,.98,.98], EdgeColor=[.98,.98,.98], Location='south', Box='on', NumColumns=3, PickableParts='none')

            plot_DataTipTemplate(app, 'SweepID+ChannelPower', chPowerLine,  [])
            plot_DataTipTemplate(app, 'SweepID+ChannelPower', binPowerLine, [])
            plot_DataTipTemplate(app, 'SweepID+ChannelPower', smoothedLine, [])
        end


        %-----------------------------------------------------------------%
        function plot_Route(app)
            delete(findobj(app.UIAxes1.Children, 'Tag', 'outROI', '-or', 'Tag', 'inROI'))

            outROITable = app.specTable(~app.specTable.filtered,:);
            inROITable  = app.filtTable;

            switch app.imgRoute_Type.Value
                case 'none'; markerSize = 1;
                otherwise;   markerSize = 8*app.imgRoute_Size.Value;
            end

            outROILine  = geoplot(app.UIAxes1, outROITable.Lat, outROITable.Long, 'Marker', '.', 'Color', app.imgRoute_Color1.BackgroundColor, 'MarkerFaceColor', app.imgRoute_Color1.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color1.BackgroundColor, 'MarkerSize', markerSize, 'LineStyle', 'none',                  'Tag', 'outROI');
            inROILine   = geoplot(app.UIAxes1,  inROITable.Lat,  inROITable.Long, 'Marker', '.', 'Color', app.imgRoute_Color2.BackgroundColor, 'MarkerFaceColor', app.imgRoute_Color2.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color2.BackgroundColor, 'MarkerSize', markerSize, 'LineStyle', app.imgRoute_Type.Value, 'Tag', 'inROI');

            plot_DataTipTemplate(app, 'Coordinates', outROILine, [])
            plot_DataTipTemplate(app, 'Coordinates', inROILine,  [])
        end


        %-----------------------------------------------------------------%
        function plot_Density(app)
            delete(app.hDensity)
            drawnow

            switch app.layout_mapPlotSourcePanel.SelectedObject
                case app.layout_mapPlotSource_rawData; tempTable = app.filtTable;
                case app.layout_mapPlotSource_binData; tempTable = app.binTable;
            end

            if app.tool_DensityButton.ImageSource == "DriveTest_Density_ON.png"; Visibility = 1;
            else;                                                                Visibility = 0;
            end

            weights = tempTable{:,3};
            if min(weights) < 0
                weights = weights+abs(min(weights));
            end

            app.hDensity = geodensityplot(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, weights,                        ...
                                                       'FaceColor','interp', 'Radius', 100*app.tool_mapPlotSize.Value, ...
                                                       'Visible', Visibility, 'Tag', 'Density', 'PickableParts', 'none');
        end
        

        %-----------------------------------------------------------------%
        function plot_Distortion(app)
            delete(app.hScatter)

            switch app.layout_mapPlotSourcePanel.SelectedObject
                case app.layout_mapPlotSource_rawData; tempTable = app.filtTable;
                case app.layout_mapPlotSource_binData; tempTable = app.binTable;
            end

            if app.tool_ScatterButton.ImageSource == "DriveTest_Scatter_ON.png"; Visibility = 1;
            else;                                                                Visibility = 0;
            end

            app.hScatter = geoscatter(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, [], tempTable{:,3},  ...
                                                   'filled', 'SizeData', 20*app.tool_mapPlotSize.Value, ...
                                                   'Visible', Visibility, 'Tag', 'Distortion');
            plot_DataTipTemplate(app, 'SweepID+ChannelPower+Coordinates', app.hScatter,  [])
        end


        %-----------------------------------------------------------------%
        function plot_Points(app)
            global RFDataHub

            delete(findobj(app.UIAxes1.Children, 'Tag', 'Points'))

            for ii = 1:height(app.pointsTable)
                if ~app.pointsTable.visible(ii)
                    continue
                end

                rowSource   = app.pointsTable.value(ii).source;
                rowIndex    = app.pointsTable.value(ii).idx;
                Coordinates = app.pointsTable.value(ii).Coordinates;

                switch app.pointsTable.type{ii}
                    case 'RFDataHub'
                        hStation = geoplot(app.UIAxes1, Coordinates(:,1), Coordinates(:,2),                                          ...
                                                        'LineStyle', 'none', 'Color', app.mapPlot_color1_2.BackgroundColor,          ...
                                                        'Marker', app.mapPlot_Type_2.Value, 'MarkerSize', app.mapPlot_size1_2.Value, ...
                                                        'MarkerFaceColor', app.mapPlot_color1_2.BackgroundColor,                     ...
                                                        'MarkerEdgeColor', app.mapPlot_color1_2.BackgroundColor,                     ...
                                                        'Tag', 'Points');
                        plot_DataTipTemplate(app, 'Coordinates+Frequency', hStation, RFDataHub(rowIndex,1:2))

                    case 'FindPeaks'
                        switch rowSource
                            case 'Dados brutos';                     hPlot = findobj(app.UIAxes2.Children, 'Tag', 'rawTable');     app.chPowerSource_rawTable.Value = 1;
                            case 'Dados processados (Data Binning)'; hPlot = findobj(app.UIAxes2.Children, 'Tag', 'binTable');     app.chPowerSource_binTable.Value = 1;
                            case 'Curva suave';                      hPlot = findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'); app.chPowerSource_smoothed.Value = 1; plot_smoothedDataVisibility(app)
                        end
                        set(hPlot, MarkerIndices=rowIndex, Visible=1)

                        geoplot(app.UIAxes1, Coordinates(:,1), Coordinates(:,2),                                          ...
                                             'LineStyle', 'none', 'Color', app.mapPlot_color1_2.BackgroundColor,          ...
                                             'Marker', app.mapPlot_Type_2.Value, 'MarkerSize', app.mapPlot_size1_2.Value, ...
                                             'MarkerFaceColor', 'none',                                                   ...
                                             'MarkerEdgeColor', app.mapPlot_color1_2.BackgroundColor,                     ...
                                             'Tag', 'Points', 'PickableParts', 'none');
                end
            end
        end


        %-----------------------------------------------------------------%
        function plot_Car(app)
            delete(app.hCar)

            idx = round(app.hTimeStamp.XData(1));
            app.hCar = geoscatter(app.UIAxes1, app.filtTable.Lat(idx), app.filtTable.Long(idx), 'filled',                                              ...
                                  'Marker', app.mapPlot_Type.Value, 'MarkerFaceColor', app.mapPlot_color1.BackgroundColor, 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 10*app.mapPlot_size1.Value, 'PickableParts', 'none', 'Tag', 'Car');
        end


        %-----------------------------------------------------------------%
        function plot_ROICallback(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)
                    filter_updateInfo(app)
            end            
        end


        %-----------------------------------------------------------------%
        function plot_DataTipTemplate(app, pType, pHandle, hTable)
            if isempty(pHandle)
                return
            end

            set(pHandle.DataTipTemplate, FontName='Calibri', FontSize=10)

            switch pType
                case 'Coordinates'
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Latitude:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Longitude:';
                    if numel(pHandle.DataTipTemplate.DataTipRows) > 2
                        pHandle.DataTipTemplate.DataTipRows(3:end) = [];
                    end

                case 'Coordinates+Frequency'
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Latitude:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Longitude:';
                    pHandle.DataTipTemplate.DataTipRows(3)       = dataTipTextRow('Frequência:', hTable.Frequency, '%.3f MHz');
                    pHandle.DataTipTemplate.DataTipRows(4)       = dataTipTextRow('Entidade:',   hTable.Name);
                    
                    pHandle.DataTipTemplate.DataTipRows          = pHandle.DataTipTemplate.DataTipRows([3:4,1:2]);

                case 'SweepID+ChannelPower+Coordinates'
                    hTable = table((1:numel(pHandle.LatitudeData))', 'VariableNames', {'ID'});

                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Latitude:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Longitude:';
                    pHandle.DataTipTemplate.DataTipRows(3)       = dataTipTextRow('ID:', hTable.ID);
                    pHandle.DataTipTemplate.DataTipRows(4)       = dataTipTextRow('Potência:', 'CData', '%.1f dBm');
                    
                    if numel(pHandle.DataTipTemplate.DataTipRows) > 4
                        pHandle.DataTipTemplate.DataTipRows(5:end) = [];
                    end

                    pHandle.DataTipTemplate.DataTipRows          = pHandle.DataTipTemplate.DataTipRows([3:4,1:2]);

                case 'SweepID+ChannelPower'
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'ID:';
                    pHandle.DataTipTemplate.DataTipRows(2)       = dataTipTextRow('Potência:', 'YData', '%.1f dBm');
            end
        end


        %-----------------------------------------------------------------%
        function plot_axesStackingOrder(app)
            refStackingOrder = {'ROI', 'Car', 'Points', 'Distortion', 'Density', 'inROI', 'outROI'};
            
            StackingOrderTag = arrayfun(@(x) x.Tag, app.UIAxes1.Children, 'UniformOutput', false)';
            newOrderIndex    = [];
        
            for ii = 1:numel(refStackingOrder)
                idx = find(strcmp(StackingOrderTag, refStackingOrder{ii}));
                newOrderIndex = [newOrderIndex, idx];
            end
        
            app.UIAxes1.Children = app.UIAxes1.Children(newOrderIndex);
        end
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function TimeStampUpdatePosition(app, timeIndex)
            idx = find(app.filtTable.FK1 == timeIndex, 1);

            if ~isempty(idx)
                app.hTimeStamp.XData(1:2)       = idx;
                app.hTimeStampLabel.Position(1) = idx;
                app.hTimeStampLabel.String      = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                set(app.hCar, 'LatitudeData', app.filtTable.Lat(idx), 'LongitudeData', app.filtTable.Long(idx))
            else
                set(app.hCar, 'LatitudeData', NaN, 'LongitudeData', NaN)
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx1, idx2)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';

            layout_LeftPanelSelectedTabControl(app, struct('Source', app.menu_Button1Icon))
            points_addTypePanelSelectionChanged(app)
            
            app.specData   = app.CallingApp.specData(idx1);
            app.Index      = [idx1, idx2];

            startup_AxesCreation(app)
            startup_General(app)
            filter_updateInfo(app)

            set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
            app.CallingApp.play_Tree.Enable = 0;
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
            app.CallingApp.play_Tree.Enable = 1;

            app.CallingApp.auxiliarWin2 = [];
            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots e a exclusão de FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    pause(.1)

                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)

                    filter_TreeSelectionChanged(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            rowHeight        = repmat({0}, 1, NN);
            rowHeight(idx)   = {'1x'};

            app.menu_MainGrid.ColumnWidth      = columnWidth;
            app.menu_Image.Layout.Column       = idx;
            app.LeftPanelGrid.RowHeight(2:end) = rowHeight;

        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Button pushed function: tool_ExportButton
        function tool_ExportFileButtonPushed(app, event)
            
            defaultFilename = class.Constants.DefaultFileName(app.CallingApp.menu_userPath.Value, 'Drive-test', app.CallingApp.report_Issue.Value);

            [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'appAnalise (*.xlsx)'}, '', defaultFilename);
            figure(app.UIFigure)
    
            if fileIndex
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

                try
                    tempFilters = app.filterTable(:, 1:2);
                    for ii = 1:height(app.filterTable)
                        switch app.filterTable.subtype{ii}
                            case 'THRESHOLD'; tempFilters.Parameters{ii} = struct('Threshold', app.filterTable.handle{ii}.Position(3));
                            case 'Circle';    tempFilters.Parameters{ii} = struct('Center',    app.filterTable.handle{ii}.Center,   'Radius',        app.filterTable.handle{ii}.Radius);
                            case 'Rectangle'; tempFilters.Parameters{ii} = struct('Position',  app.filterTable.handle{ii}.Position, 'RotationAngle', app.filterTable.handle{ii}.RotationAngle);
                            case 'Polygon';   tempFilters.Parameters{ii} = struct('Position',  app.filterTable.handle{ii}.Position);
                        end
                    end
    
                    FullFileName = fullfile(filePath, fileName);
        
                    fileID = fopen(replace(FullFileName, '.xlsx', '.cfg'), "wt");
                    fprintf(fileID, '%s', sprintf('EMISSÃO:\n%s\n\n\n', extractAfter(app.emissionsList.Value, ': ')));
                    fprintf(fileID, '%s', sprintf('INFORMAÇÕES GERAIS:\n%s\n\n', strjoin(app.taskMetaData.Value, '\n')));
                    fprintf(fileID, 'FILTRO(S):\n%s\n\n\n',  jsonencode(tempFilters, 'PrettyPrint', true));
                    fprintf(fileID, 'DATA BINNING:\n%s\n%s', jsonencode(struct('Type', app.binTable_Type.Value, 'Factor', app.binTable_Factor.Value, 'Fcn', app.binTable_Fcn.Value)), app.binTable_Info.Text);
                    fclose(fileID);
        
                    writetable(app.specTable,              FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'replacefile',    'Sheet', 'specTable')
                    writetable(app.filtTable(:,[1:3,6:7]), FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'filtTable')
                    writetable(app.binTable,               FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'binTable')

                    kmlwriteline(replace(FullFileName, '.xlsx', '.kml'), app.specTable.Lat, app.specTable.Long, 'Color', 'red', 'LineWidth', 3);

                catch ME
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
                end

                delete(d)
            end

        end

        % Image clicked function: emissionListRefresh
        function emission_RefreshListImageClicked(app, event)
            
            preSelection = extractAfter(app.emissionsList.Value, ': ');
            
            try
                app.specData = app.CallingApp.specData(app.Index(1));
                startup_EmissionsList(app)
    
                idx = find(contains(app.emissionsList.Items, preSelection), 1);
                if ~isempty(idx)
                    app.emissionsList.Value = app.emissionsList.Items(idx);
                end
                
                startup_GeneralMetaData(app)
                tool_MetaDataInfo(app)
                binning_specTableCreation(app)
                filter_updateInfo(app)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end

        end

        % Value changed function: emissionsList
        function emission_ValueChanged(app, event)

            tool_MetaDataInfo(app)
            binning_specTableCreation(app)
            filter_updateInfo(app)
            
        end

        % Selection changed function: filter_roiOptions
        function filter_ROISelectionChanged(app, event)
            
            if ~isempty(app.filter_roiOptions.SelectedNodes) && app.filter_roiOptions.SelectedNodes.NodeData
                app.filter_addImage.Enable = 1;
            else
                app.filter_addImage.Enable = 0;
            end
            
        end

        % Selection changed function: filter_Tree
        function filter_TreeSelectionChanged(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx = app.filter_Tree.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.filterTable)
                    if ii ~= idx
                        set(app.filterTable.handle{ii}, 'InteractionsAllowed', 'none')
    
                    else
                        if app.filterTable.type == "Level"
                            set(app.filterTable.handle{ii}, 'InteractionsAllowed',  'translate')
                        else
                            set(app.filterTable.handle{ii}, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
                uistack(app.filterTable.handle{idx}, "top")
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addImageCallback(app, event)
            
            NodeData = app.filter_roiOptions.SelectedNodes.NodeData;
            
            app.filter_roiOptions.SelectedNodes = [];
            filter_ROISelectionChanged(app)

            set(app.filter_Tree, 'SelectedNodes', [], 'Enable', 0)
            set(app.filter_ContextMenu.Children, Enable=0)

            disableDefaultInteractivity(app.UIAxes1)
            disableDefaultInteractivity(app.UIAxes2)
            pause(.1)

            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'THRESHOLD';                        
                    THR  = min(app.filtTable.chPower);
                    hROI = images.roi.Line(app.UIAxes2, 'Position', [1 THR; height(app.filtTable) THR],  ...
                                                        'Color', 'red', 'MarkerSize', 4, 'LineWidth', 1, ...
                                                        'Deletable', 0, 'InteractionsAllowed', 'translate', 'Tag', 'ROI');
                otherwise
                    switch NodeData
                        case 2; Type = 'Geographic ROI'; SubType = 'Circle';    roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 3; Type = 'Geographic ROI'; SubType = 'Rectangle'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 4; Type = 'Geographic ROI'; SubType = 'Polygon';   roiFcn = 'drawpolygon';   roiNameArgument = '';
                            
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI");', roiFcn, roiNameArgument))
            end
            enableDefaultInteractivity(app.UIAxes1)
            enableDefaultInteractivity(app.UIAxes2)

            if isempty(hROI.Position)
                delete(hROI)
                
                app.filter_Tree.Enable = 1;
                if ~isempty(app.filter_Tree.Children)
                    app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                    filter_TreeSelectionChanged(app)
                end

                return
            end

            addlistener(hROI, 'MovingROI', @app.plot_ROICallback);
            addlistener(hROI, 'ROIMoved',  @app.plot_ROICallback);
            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plotFcn.cleanupFcn(src));

            app.filterTable(end+1,:) = {Type, SubType, {hROI}, 1};
            filter_TreeBuilding(app)
            filter_updateInfo(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);

                case app.filter_delAllButton
                    idx = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx)
                cellfun(@(x) delete(x), app.filterTable.handle(idx)); 
                app.filterTable(idx,:) = [];

                filter_TreeBuilding(app)
                filter_updateInfo(app)
            end

        end

        % Selection changed function: points_addTypePanel
        function points_addTypePanelSelectionChanged(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;
            switch selectedButton
                case app.points_addRFDataHub; app.points_addValueGrid.RowHeight = {17,22,22,22,0,0,0,0};
                case app.points_addFindPeaks;  app.points_addValueGrid.RowHeight = {0,0,0,0,17,22,22,22};
            end
            
        end

        % Image clicked function: points_addImage
        function points_addImageImageClicked(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;

            try
                switch selectedButton
                    %-----------------------------------------------------%
                    case app.points_addRFDataHub
                        global RFDataHub
            
                        if isempty(char(app.points_addSubtype1TextArea.Value))
                            return
                        end
                        entryText = char(strjoin(app.points_addSubtype1TextArea.Value, ','));
            
                        switch app.points_addSubtype1DropDown.Value
                            case 'Índices de registros do RFDataHub'
                                idx = regexp(entryText, '#(\d+)', 'tokens');
                                idx = str2double([idx{:}]);
            
                            case 'Lista de frequências'
                                freqList = regexp(entryText, '(\d+.\d+)', 'tokens');
                                freqList = cellfun(@(x) str2double(x), [freqList{:}]);
            
                                filterTempTable = table('Size',          [0, 9],                                                                      ...
                                                        'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                                                        'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'});
            
                                for ii = 1:numel(freqList)
                                    if ii == 1; Order = 'Node';  RelatedID = -1;
                                    else;       Order = 'Child'; RelatedID = 1;
                                    end
                                    filterTempTable(ii,:) = {Order, ii, RelatedID, 'Frequência', '=', 1, {freqList(ii)}, true, ''};
                                end            
                                idx = find(fcn.TableFiltering(RFDataHub, filterTempTable));
                        end
        
                        if ~isempty(idx)
                            NN = numel(idx);
                            distanceArray = deg2km(distance(RFDataHub.Latitude(idx),   RFDataHub.Longitude(idx),  ...
                                                            app.specData.GPS.Latitude, app.specData.GPS.Longitude));
                            
                            idx(distanceArray > app.points_addSubtype1Distance.Value) = [];
                            MM = numel(idx);
                            if ~MM
                                error('Não identificada estação de telecomunicações que atenda às especificações...')
                            end
                            
                            newRow = {'RFDataHub', struct('source', app.points_addSubtype1DropDown.Value, 'idx', idx, 'Coordinates', RFDataHub{idx,6:7}), true};
                            points_TableCheck(app, newRow)
        
                            msg = sprintf('Identificada(s) %d estação(ões) de telecomunicações que atende(m) às especificações.', NN);
                            if MM ~= NN
                                msg = sprintf('%s Contudo, apenas %d atende(m) à distância máxima especificada.', msg, MM);
                            end
                        else
                            error('Não identificada estação de telecomunicações que atenda às especificações...')
                        end
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
    
                    %---------------------------------------------------------%
                    case app.points_addFindPeaks
                        [idx, Coordinates] = points_FindPeaks(app, app.points_addSubtype2DropDown.Value, app.points_addSubtype2NPeaks.Value, app.points_addSubtype2Distance.Value/1000);
                        newRow = {'FindPeaks', struct('source', app.points_addSubtype2DropDown.Value, 'idx', idx, 'Coordinates', Coordinates), true};
                        points_TableCheck(app, newRow)

                        msg = sprintf('Identificado(s) %d ponto(s) que atende(m) às especificações.', numel(idx));
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                end

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
                return
            end

            points_TreeBuilding(app)
            plot_Points(app)
            plot_axesStackingOrder(app)

        end

        % Value changed function: points_addSubtype1TextArea
        function points_addSubtype1TextAreaValueChanged(app, event)

            app.points_addSubtype1TextArea.Value = strtrim(app.points_addSubtype1TextArea.Value);
            
        end

        % Value changed function: binTable_Type
        function binTable_TypeChanged(app, event)

            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    set(app.binTable_FactorLabel, 'Text', 'Comprimento <i>bin</i> (metros):')
                    set(app.binTable_Factor, 'Limits', [50, 1500], 'Value', 100, 'Step', 50)

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    set(app.binTable_FactorLabel, 'Text', 'Casas decimais:')
                    set(app.binTable_Factor, 'Limits', [1, 10], 'Value', 6, 'Step', 1)
            end

            binTable_ValueChanged(app)
            
        end

        % Value changed function: binTable_Factor, binTable_Fcn
        function binTable_ValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            binning_binTable(app)
            binning_UITable(app)

            if app.layout_mapPlotSource_binData.Value
                plot_MainControl(app)
            else                
                h = findall(app.UIAxes2, 'Tag', 'binTable');
                h.YData = app.binTable.binPower(app.filtTable.FK2);
            end

            delete(d)
            
        end

        % Selection changed function: layout_mapPlotSourcePanel
        function plot_RefreshCallback(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            plot_MainControl(app)
            delete(d)

        end

        % Image clicked function: tool_DensityButton, tool_ScatterButton
        function plot_DistortionOrDensityVisibility(app, event)
            
            switch event.Source
                case app.tool_ScatterButton
                    if app.hScatter.Visible
                        app.hScatter.Visible = 0;
                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        app.hScatter.Visible = 1;
                        app.hDensity.Visible = 0;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.tool_DensityButton
                    if app.hDensity.Visible
                        app.hDensity.Visible = 0;
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        app.hScatter.Visible = 0;
                        app.hDensity.Visible = 1;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Button pushed function: imgRoute_Color1, imgRoute_Color2, 
        % ...and 2 other components
        function plot_colorCallbacks(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;

                switch event.Source
                    case app.imgRoute_Color1
                        set(findobj(app.UIAxes1.Children, 'Tag', 'outROI'), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.imgRoute_Color2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'inROI'),  'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.mapPlot_color1
                        set(app.hCar, 'MarkerFaceColor', selectedColor)

                    case app.mapPlot_color1_2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                end
            end

        end

        % Callback function: Colormap, imgRoute_Size, imgRoute_Type, 
        % ...and 4 other components
        function plot_layoutCallbacks(app, event)
            
            try
                switch event.Source
                    case app.imgRoute_Type
                        switch event.Value
                            case 'none'; markerSize = 1;
                            otherwise;   markerSize = 8*app.imgRoute_Size.Value;
                        end
                        set(findobj(app.UIAxes1.Children, 'Tag', 'inROI'), 'LineStyle', event.Value, 'MarkerSize', markerSize)

                    case app.imgRoute_Size
                        if ~strcmp(app.imgRoute_Type.Value, 'none')
                            set(findobj(app.UIAxes1.Children, 'Tag', 'outROI'), 'MarkerSize', 8*event.Value)
                            set(findobj(app.UIAxes1.Children, 'Tag', 'inROI'),  'MarkerSize', 8*event.Value)
                        end

                    case app.mapPlot_size1
                        set(app.hCar, 'SizeData', 10*event.Value)

                    case app.mapPlot_Type
                        set(app.hCar, 'Marker', event.Value)

                    case app.mapPlot_size1_2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'MarkerSize', event.Value)

                    case app.mapPlot_Type_2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'Marker', event.Value)

                    case app.Colormap
                        colormap(app.UIAxes1, app.Colormap.Value)
                end
            catch
            end            

        end

        % Value changed function: tool_mapPlotSize
        function plot_SizeChangedCallback(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            set(app.hScatter, 'SizeData', 20*event.Value)
            set(app.hDensity, 'Radius',  100*event.Value)
            drawnow

            delete(d)

        end

        % Value changed function: chPowerSource_binTable, 
        % ...and 1 other component
        function plot_SourceChanged(app, event)
            
            switch event.Source
                case app.chPowerSource_rawTable; Tag = 'rawTable';
                case app.chPowerSource_binTable; Tag = 'binTable';
            end

            h = findall(app.UIAxes2, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: chPowerSource_smoothed
        function plot_smoothedDataVisibility(app, event)
            
            if app.chPowerSource_smoothed.Value
                set(app.Smoothed_Grid.Children, 'Enable', 1)
                set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), Visible=1)

            else
                set(app.Smoothed_Grid.Children, 'Enable', 0)
                set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), Visible=0)
            end            
            
        end

        % Value changed function: Smoothed_Factor, Smoothed_Type
        function plot_smoothedDataUpdate(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), YData=smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value))
            delete(d)
            
        end

        % Image clicked function: layout_mapPlotLock
        function plot_LockImageClicked(app, event)

            if app.CallingApp.play_Tree.Enable
                idx = [];
                for ii = 1:numel(app.CallingApp.specData)
                    if isequal(rmfield(struct(app.CallingApp.specData(ii)), 'UserData'), rmfield(struct(app.specData), 'UserData'))
                        idx = ii;
                        break
                    end
                end
    
                if isempty(idx)
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                    return
                end

                if app.CallingApp.play_Tree.SelectedNodes.NodeData ~= idx
                    if isempty(app.CallingApp.play_PlotPanel.UserData) || (app.CallingApp.play_PlotPanel.UserData.NodeData ~= idx)
                        hTreeNode      = findall(app.CallingApp.play_Tree, '-not', 'Type', 'uitree');

                        hTreeNodeData  = arrayfun(@(x) x.NodeData, hTreeNode, 'UniformOutput', false);
                        hTreeNodeIndex = find(cellfun(@(x) isequal(x, idx), hTreeNodeData), 1); 

                        if isempty(hTreeNodeIndex)
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                            return
                        end

                        app.CallingApp.auxiliarWinFunction(app, hTreeNode(hTreeNodeIndex))
                    end
                end

                app.CallingApp.play_Tree.Enable = 0;
                set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';

            else
                app.CallingApp.play_Tree.Enable = 1;
                set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockOpen.png';
            end

        end

        % Callback function: points_Tree
        function points_TreeCheckedNodesChanged(app, event)
            
            visibleNodeData   = arrayfun(@(x) x.NodeData, app.points_Tree.CheckedNodes);
            invisibleNodeData = setdiff(1:height(app.pointsTable), visibleNodeData);

            app.pointsTable.visible(visibleNodeData)   = true;
            app.pointsTable.visible(invisibleNodeData) = false;

            plot_Points(app)
            plot_axesStackingOrder(app)
            
        end

        % Menu selected function: points_delButton
        function points_delButtonMenuSelected(app, event)
            
            if isempty(app.pointsTable) || isempty(app.points_Tree.SelectedNodes)
                return
            end

            idx = arrayfun(@(x) x.NodeData, app.points_Tree.SelectedNodes);
    
            if ~isempty(idx)
                app.pointsTable(idx,:) = [];

                points_TreeBuilding(app)
                plot_Points(app)
                plot_axesStackingOrder(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.menu_MainGrid = uigridlayout(app.LeftPanelGrid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = 'LT_LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'LT_Dots6.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Pontos de interesse';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'LT_Pin2.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Data binning';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'LT_Detection2.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Layout';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'LT_Layers2.png';
            app.Tab1_Panel = uigridlayout(app.LeftPanelGrid);
            app.emissionListGrid = uigridlayout(app.Tab1_Panel);
            app.emissionListLabel = uilabel(app.emissionListGrid);
            app.emissionListLabel.Text = 'Emissão';
            app.emissionListRefresh = uiimage(app.emissionListGrid);
            app.emissionListRefresh.ImageClickedFcn = createCallbackFcn(app, @emission_RefreshListImageClicked, true);
            app.emissionListRefresh.ImageSource = 'LT_reload.png';
            app.emissionsList = uidropdown(app.Tab1_Panel);
            app.emissionsList.Items = {};
            app.emissionsList.ValueChangedFcn = createCallbackFcn(app, @emission_ValueChanged, true);
            app.emissionsList.Value = {};
            app.taskMetaData = uitextarea(app.Tab1_Panel);
            app.taskMetaData.Editable = 'off';
            app.filter_TreeLabel = uilabel(app.Tab1_Panel);
            app.filter_TreeLabel.Text = 'Filtros';
            app.filter_roiOptions = uitree(app.Tab1_Panel);
            app.filter_roiOptions.SelectionChangedFcn = createCallbackFcn(app, @filter_ROISelectionChanged, true);
            app.levelFilterNode = uitreenode(app.filter_roiOptions);
            app.levelFilterNode.Icon = 'LT_OCC.png';
            app.levelFilterNode.Text = 'NÍVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.filter_roiOptions);
            app.geoFilterNode.Icon = 'DriveTest_Scatter_OFF.png';
            app.geoFilterNode.Text = 'GEOGRÁFICO';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CÍRCULO';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RETÂNGULO';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLÍGONO';
            app.filter_addImageGrid = uigridlayout(app.Tab1_Panel);
            app.filter_addImage = uiimage(app.filter_addImageGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addImageCallback, true);
            app.filter_addImage.Enable = 'off';
            app.filter_addImage.ImageSource = 'LT_play.png';
            app.filter_Tree = uitree(app.Tab1_Panel);
            app.filter_Tree.SelectionChangedFcn = createCallbackFcn(app, @filter_TreeSelectionChanged, true);
            app.Tab2_Panel = uigridlayout(app.LeftPanelGrid);
            app.points_TreeLabel = uilabel(app.Tab2_Panel);
            app.points_TreeLabel.Text = 'Pontos de interesse';
            app.points_addTypePanel = uibuttongroup(app.Tab2_Panel);
            app.points_addTypePanel.SelectionChangedFcn = createCallbackFcn(app, @points_addTypePanelSelectionChanged, true);
            app.points_addRFDataHub = uiradiobutton(app.points_addTypePanel);
            app.points_addRFDataHub.Text = {'Estações de telecomunicações'; '(RFDataHub)'};
            app.points_addRFDataHub.Value = true;
            app.points_addFindPeaks = uiradiobutton(app.points_addTypePanel);
            app.points_addFindPeaks.Text = {'Picos da potência'; 'do canal'};
            app.points_addValuePanel = uipanel(app.Tab2_Panel);
            app.points_addValueGrid = uigridlayout(app.points_addValuePanel);
            app.points_addSubtype1Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype1Label.Text = 'Tipo de registro:';
            app.points_addSubtype1DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype1DropDown.Items = {'Índices de registros do RFDataHub', 'Lista de frequências'};
            app.points_addSubtype1DropDown.Value = 'Lista de frequências';
            app.points_addSubtype1TextArea = uitextarea(app.points_addValueGrid);
            app.points_addSubtype1TextArea.ValueChangedFcn = createCallbackFcn(app, @points_addSubtype1TextAreaValueChanged, true);
            app.points_addSubtype1DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype1DistanceLabel.Text = 'Distância máxima entre estação e local da monitoração (km):';
            app.points_addSubtype1Distance = uieditfield(app.points_addValueGrid, 'numeric');
            app.points_addSubtype1Distance.Limits = [1 Inf];
            app.points_addSubtype1Distance.RoundFractionalValues = 'on';
            app.points_addSubtype1Distance.ValueDisplayFormat = '%d';
            app.points_addSubtype1Distance.Value = 200;
            app.points_addSubtype2Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype2Label.Text = 'Fonte da informação:';
            app.points_addSubtype2DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype2DropDown.Items = {'Dados brutos', 'Dados processados (Data Binning)', 'Curva suave'};
            app.points_addSubtype2DropDown.Value = 'Dados brutos';
            app.points_addSubtype2NPeaksLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2NPeaksLabel.Text = 'Número de picos:';
            app.points_addSubtype2NPeaks = uispinner(app.points_addValueGrid);
            app.points_addSubtype2NPeaks.Limits = [1 100];
            app.points_addSubtype2NPeaks.RoundFractionalValues = 'on';
            app.points_addSubtype2NPeaks.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2NPeaks.Value = 1;
            app.points_addSubtype2DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2DistanceLabel.Text = 'Distância mínima entre picos (metros):';
            app.points_addSubtype2Distance = uispinner(app.points_addValueGrid);
            app.points_addSubtype2Distance.Step = 100;
            app.points_addSubtype2Distance.Limits = [0 10000];
            app.points_addSubtype2Distance.RoundFractionalValues = 'on';
            app.points_addSubtype2Distance.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2Distance.Value = 1000;
            app.points_addImage = uiimage(app.Tab2_Panel);
            app.points_addImage.ImageClickedFcn = createCallbackFcn(app, @points_addImageImageClicked, true);
            app.points_addImage.ImageSource = 'LT_Add.png';
            app.points_Tree = uitree(app.Tab2_Panel, 'checkbox');
            % Assign Checked Nodes
            app.points_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @points_TreeCheckedNodesChanged, true);
            app.Tab3_Panel = uigridlayout(app.LeftPanelGrid);
            app.binTable_Grid = uigridlayout(app.Tab3_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; ' '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Varreduras'; '  '};
            app.binTable_Info = uilabel(app.binTable_Grid);
            app.binTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dede'; 'dee'};
            app.Panel = uipanel(app.Tab3_Panel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.binTable_FactorLabel = uilabel(app.GridLayout2);
            app.binTable_FactorLabel.Interpreter = 'html';
            app.binTable_FactorLabel.Text = 'Comprimento <i>bin</i> (metros):';
            app.binTable_Factor = uispinner(app.GridLayout2);
            app.binTable_Factor.Step = 50;
            app.binTable_Factor.Limits = [50 1500];
            app.binTable_Factor.RoundFractionalValues = 'on';
            app.binTable_Factor.ValueDisplayFormat = '%.0f';
            app.binTable_Factor.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Factor.Value = 100;
            app.binTable_FcnLabel = uilabel(app.GridLayout2);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.GridLayout2);
            app.binTable_Fcn.Items = {'min', 'mean', 'median', 'rms', 'max'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Fcn.Value = 'mean';
            app.binTable_TypeLabel = uilabel(app.GridLayout2);
            app.binTable_TypeLabel.Text = 'Tipo';
            app.binTable_Type = uidropdown(app.GridLayout2);
            app.binTable_Type.Items = {'Grid retangular - Comprimento bin', 'Grid retangular - Coordenadas geográficas truncadas'};
            app.binTable_Type.ValueChangedFcn = createCallbackFcn(app, @binTable_TypeChanged, true);
            app.binTable_Type.Value = 'Grid retangular - Comprimento bin';
            app.DatabinningLabel = uilabel(app.Tab3_Panel);
            app.DatabinningLabel.Text = 'Data binning';
            app.Tab4_Panel = uigridlayout(app.LeftPanelGrid);
            app.layout_mapPlotLabel = uilabel(app.Tab4_Panel);
            app.layout_mapPlotLabel.Text = 'Eixo geográfico';
            app.layout_mapPlotLock = uiimage(app.Tab4_Panel);
            app.layout_mapPlotLock.ImageClickedFcn = createCallbackFcn(app, @plot_LockImageClicked, true);
            app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';
            app.layout_mapPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_mapPlotGrid = uigridlayout(app.layout_mapPlotPanel);
            app.layout_mapPlotSourceLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotSourceLabel.Text = 'Fonte da informação:';
            app.layout_mapPlotSourcePanel = uibuttongroup(app.layout_mapPlotGrid);
            app.layout_mapPlotSourcePanel.SelectionChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.layout_mapPlotSource_rawData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_rawData.Text = 'Dados brutos';
            app.layout_mapPlotSource_rawData.Value = true;
            app.layout_mapPlotSource_binData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_binData.Text = 'Dados processados (Data Binning)';
            app.mapPlot_Subtitle11 = uilabel(app.layout_mapPlotGrid);
            app.mapPlot_Subtitle11.Text = 'Layout:';
            app.mapPlot_Panel11 = uipanel(app.layout_mapPlotGrid);
            app.mapPlot_Grid11 = uigridlayout(app.mapPlot_Panel11);
            app.imgRoute_TypeLabel = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel.Text = 'Rota:';
            app.imgRoute_Type = uidropdown(app.mapPlot_Grid11);
            app.imgRoute_Type.Items = {'none', ':', '-'};
            app.imgRoute_Type.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.imgRoute_Type.Value = '-';
            app.imgRoute_Panel = uipanel(app.mapPlot_Grid11);
            app.imgRoute_Grid = uigridlayout(app.imgRoute_Panel);
            app.imgRoute_Color1 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color1.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.imgRoute_Color1.Text = '';
            app.imgRoute_Color2 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color2.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.imgRoute_Color2.Text = '';
            app.imgRoute_Size = uislider(app.imgRoute_Grid);
            app.imgRoute_Size.Limits = [1 9];
            app.imgRoute_Size.MajorTicks = [];
            app.imgRoute_Size.MajorTickLabels = {''};
            app.imgRoute_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.imgRoute_Size.MinorTicks = [];
            app.imgRoute_Size.Value = 1;
            app.imgRoute_TypeLabel_2 = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel_2.Text = 'Veículo:';
            app.mapPlot_Type = uidropdown(app.mapPlot_Grid11);
            app.mapPlot_Type.Items = {'none', 'o', 'square', '^'};
            app.mapPlot_Type.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.mapPlot_Type.Value = 'square';
            app.mapPlot_Property1Panel = uipanel(app.mapPlot_Grid11);
            app.mapPlot_Property1Grid = uigridlayout(app.mapPlot_Property1Panel);
            app.mapPlot_color1 = uibutton(app.mapPlot_Property1Grid, 'push');
            app.mapPlot_color1.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.mapPlot_color1.Text = '';
            app.mapPlot_size1 = uislider(app.mapPlot_Property1Grid);
            app.mapPlot_size1.Limits = [1 19];
            app.mapPlot_size1.MajorTicks = [];
            app.mapPlot_size1.MajorTickLabels = {''};
            app.mapPlot_size1.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.mapPlot_size1.MinorTicks = [];
            app.mapPlot_size1.Value = 10;
            app.ColormapLabel = uilabel(app.mapPlot_Grid11);
            app.ColormapLabel.Text = {'Mapa de'; 'cor:'};
            app.Colormap = uidropdown(app.mapPlot_Grid11);
            app.Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.Colormap.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.Colormap.Value = 'jet';
            app.imgRoute_TypeLabel_3 = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel_3.Text = 'Pontos interesse:';
            app.mapPlot_Type_2 = uidropdown(app.mapPlot_Grid11);
            app.mapPlot_Type_2.Items = {'none', 'o', 'square', '^'};
            app.mapPlot_Type_2.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.mapPlot_Type_2.Value = '^';
            app.mapPlot_Property1Panel_2 = uipanel(app.mapPlot_Grid11);
            app.mapPlot_Property1Grid_2 = uigridlayout(app.mapPlot_Property1Panel_2);
            app.mapPlot_color1_2 = uibutton(app.mapPlot_Property1Grid_2, 'push');
            app.mapPlot_color1_2.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.mapPlot_color1_2.Text = '';
            app.mapPlot_size1_2 = uislider(app.mapPlot_Property1Grid_2);
            app.mapPlot_size1_2.Limits = [6 12];
            app.mapPlot_size1_2.MajorTicks = [];
            app.mapPlot_size1_2.MajorTickLabels = {''};
            app.mapPlot_size1_2.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.mapPlot_size1_2.MinorTicks = [];
            app.mapPlot_size1_2.Value = 9;
            app.layout_cartesianPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_cartesianPlotTitle.Text = 'Eixo cartesiano';
            app.layout_cartesianPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_cartesianPlotGrid = uigridlayout(app.layout_cartesianPlotPanel);
            app.layout_cartesianPlotLabel = uilabel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotLabel.Text = 'Fonte da informação:';
            app.layout_cartesianPlotSubPanel = uipanel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotSubGrid = uigridlayout(app.layout_cartesianPlotSubPanel);
            app.chPowerSource_rawTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_rawTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.chPowerSource_rawTable.Text = 'Dados brutos';
            app.chPowerSource_rawTable.Value = true;
            app.chPowerSource_binTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_binTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.chPowerSource_binTable.Text = 'Dados processados (Data Binning)';
            app.chPowerSource_smoothed = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.chPowerSource_smoothed.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataVisibility, true);
            app.chPowerSource_smoothed.Text = 'Curva suave';
            app.chPowerSource_smoothedPanel = uipanel(app.layout_cartesianPlotSubGrid);
            app.Smoothed_Grid = uigridlayout(app.chPowerSource_smoothedPanel);
            app.Smoothed_TypeLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_TypeLabel.Enable = 'off';
            app.Smoothed_TypeLabel.Text = 'Tipo:';
            app.Smoothed_Type = uidropdown(app.Smoothed_Grid);
            app.Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.Smoothed_Type.Enable = 'off';
            app.Smoothed_Type.Value = 'gaussian';
            app.Smoothed_FactorLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_FactorLabel.Enable = 'off';
            app.Smoothed_FactorLabel.Text = 'Fator:';
            app.Smoothed_Factor = uispinner(app.Smoothed_Grid);
            app.Smoothed_Factor.Step = 0.1;
            app.Smoothed_Factor.Limits = [0 1];
            app.Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.Smoothed_Factor.Enable = 'off';
            app.Smoothed_Factor.Value = 0.25;
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
            app.Document = uigridlayout(app.Grid);
            app.axesPanel = uipanel(app.Document);
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ExportButton = uibutton(app.toolGrid, 'push');
            app.tool_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @tool_ExportFileButtonPushed, true);
            app.tool_ExportButton.Icon = 'Export_24.png';
            app.tool_ExportButton.Tooltip = {'Exportar tabela'};
            app.tool_ExportButton.Text = '';
            app.tool_Serarator1 = uiimage(app.toolGrid);
            app.tool_Serarator1.Enable = 'off';
            app.tool_Serarator1.ImageSource = 'LT_LineV.png';
            app.tool_ScatterButton = uiimage(app.toolGrid);
            app.tool_ScatterButton.ImageClickedFcn = createCallbackFcn(app, @plot_DistortionOrDensityVisibility, true);
            app.tool_ScatterButton.Tooltip = {'Distorção'};
            app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
            app.tool_DensityButton = uiimage(app.toolGrid);
            app.tool_DensityButton.ImageClickedFcn = createCallbackFcn(app, @plot_DistortionOrDensityVisibility, true);
            app.tool_DensityButton.Tooltip = {'Densidade'};
            app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
            app.tool_mapPlotSize = uislider(app.toolGrid);
            app.tool_mapPlotSize.Limits = [1 19];
            app.tool_mapPlotSize.MajorTicks = [1 10 19];
            app.tool_mapPlotSize.MajorTickLabels = {''};
            app.tool_mapPlotSize.ValueChangedFcn = createCallbackFcn(app, @plot_SizeChangedCallback, true);
            app.tool_mapPlotSize.MinorTicks = [1.6 2.2 2.8 3.4 4 4.6 5.2 5.8 6.4 7 7.6 8.2 8.8 9.4 10 10.6 11.2 11.8 12.4 13 13.6 14.2 14.8 15.4 16 16.6 17.2 17.8 18.4];
            app.tool_mapPlotSize.Value = 1;
            app.tool_Serarator2 = uiimage(app.toolGrid);
            app.tool_Serarator2.Enable = 'off';
            app.tool_Serarator2.ImageSource = 'LT_LineV.png';
            app.tool_SelectedEmision = uilabel(app.toolGrid);
            app.tool_SelectedEmision.Text = {'R&S FSL'; 'ID1: 30.000 - 108.000 MHz @ 103.500 MHz ⌂ 256.000 kHz'};
            app.tool_nRowsGrid = uigridlayout(app.toolGrid);
            app.tool_nRows = uilabel(app.tool_nRowsGrid);
            app.tool_nRows.Text = {'0 de 0 varreduras'; '85.4 %'};
            app.tool_nRowsIcon = uiimage(app.tool_nRowsGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.ImageSource = 'LT_Filter2.png';
            app.tool_resetButton = uiimage(app.tool_nRowsGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'LT_Dots.png';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_delButtonMenuSelected, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end