classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout22                  matlab.ui.container.GridLayout
        GridLayout23                  matlab.ui.container.GridLayout
        GridLayout27                  matlab.ui.container.GridLayout
        Button                        matlab.ui.control.Button
        Button3                       matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        GERALTab                      matlab.ui.container.Tab
        GridLayout26                  matlab.ui.container.GridLayout
        EmissoDropDown                matlab.ui.control.DropDown
        GridLayout28                  matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        EmissoDropDownLabel           matlab.ui.control.Label
        OutrasinformaesTextArea       matlab.ui.control.TextArea
        OutrasinformaesTextAreaLabel  matlab.ui.control.Label
        ArquivosrelacionadosLabel     matlab.ui.control.Label
        Tree2                         matlab.ui.container.CheckBoxTree
        arquivo1binNode               matlab.ui.container.TreeNode
        arquivo2binNode               matlab.ui.container.TreeNode
        PLOTTab                       matlab.ui.container.Tab
        GridLayout24                  matlab.ui.container.GridLayout
        GridLayout25                  matlab.ui.container.GridLayout
        AlgoritmoDropDown             matlab.ui.control.DropDown
        AlgoritmoDropDownLabel        matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        AspectosgeraisNode            matlab.ui.container.TreeNode
        RotaNode                      matlab.ui.container.TreeNode
        ScatterNode                   matlab.ui.container.TreeNode
        DensidadeNode                 matlab.ui.container.TreeNode
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        
        repoData
        Filters

        UIAxes1
        
    end
    
    methods (Access = private)

        function Fcn_StartUp(app)

            if height(app.Table.Data)
                % UF & Node filter
                set(app.UF,   'Items', [''; unique(app.Table.Data.Site_State_Code)], 'Value', '')
                set(app.City, 'Items', {''}, 'Enable', 0);

                app.ROTANode.Items = unique(app.Table.Data.Equipment_ID);
                
                % Band filter
                app.FreqCenter.Value = min(app.Table.Data.Start_Frequency);
                app.BW.Value  = max(app.Table.Data.Stop_Frequency);
    
                % Observation time filter
                firstTimeStamp = min(app.Table.Data.Initial_Time);
                lastTimeStamp  = max(app.Table.Data.Final_Time);
    
                app.DatePicker1.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker1, 'Value', firstTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
                
                app.DatePicker2.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker2, 'Value', lastTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
   
                app.FilesNumber.Text = num2str(numel(unique(app.Table.Data.Filename)));
            else
                app.FilesNumber.Text = '0';
            end
    
            app.Spinner1.Value = 0;
            app.Spinner2.Value = 0;
            app.Spinner3.Value = 23;
            app.Spinner4.Value = 59;

            Fcn_FilterPanel(app)
            
        end


        function ROIEvents(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)
                    
%                     ind1 = app.traceInfo.SelectedNode;
                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    
                    app.Playback_peakCF.Value               = round(app.line_Mkr.Position(1), 3);
                    app.line_ClearWrite.MarkerIndices(ind2) = round((app.Playback_peakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    % Se tiver apenas um marcador, então a string fica como
                    % cell. Caso tenha mais de um marcador, então a string
                    % fica como char.
                    markerTag = findobj('Type', 'Text', 'String', sprintf('  %d', ind2));
                    if isempty(markerTag)
                        markerTag = findobj('Type', 'Text', 'String', {sprintf('  %d', ind2)});
                    end
                    markerTag.Position(1:2) = [app.Playback_peakCF.Value, app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ind2))];
                    
                    set(app.Playback_peakTree.Children(ind2), 'Text', sprintf("%d: %.3f MHz", ind2, app.Playback_peakCF.Value), ...
                                                              'NodeData', ind2)
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    ind1 = app.traceInfo.SelectedNode;
                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    
                    newIndex = round((app.Playback_peakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    app.specData(ind1).Emissions(ind2,1:2) = [newIndex, app.Playback_peakCF.Value];
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions);
                    
                    selectedEmission = find(app.specData(ind1).Emissions(:,1) == newIndex, 1);
                    Playback_TreeBuilding(app, selectedEmission)
                    Plot_Spectrum(app, ind1, selectedEmission)
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.specData = app.CallingApp.specData(idx);
            app.specData_idx = idx;

            Fcn1_Panel(app)
            Fcn2_Plot(app);

% 
% 
% 
% 
%             app.Tree.SelectedNodes = app.Tree.Children(1);
%             TreeSelectionChanged(app)
% 
%             % geoAxes
%             app.UIAxes1 = geoaxes(app.GridLayout23, 'FontSize', 6, 'Toolbar', [], 'Units', 'pixels');
% 
%             app.UIAxes1.Layout.Row    = [3, 5];
%             app.UIAxes1.Layout.Column = 1;
% 
%             app.UIAxes1.LatitudeLabel.String = '';
%             app.UIAxes1.LongitudeLabel.String = '';
% 
% 
%             % specAxes
%             xData = linspace(rawData.MetaData.FreqStart/1e+6, rawData.MetaData.FreqStop/1e+6, rawData.MetaData.DataPoints);
%             
%             hold(app.UIAxes2, 'on')
%             plot(app.UIAxes2, xData, rawData.statsData(:, 1), 'Tag', 'Drive-test: MinHold',  'Color', app.CallingApp.General.Colors(1,:));
%             plot(app.UIAxes2, xData, rawData.statsData(:, 3), 'Tag', 'Drive-test: MeanHold', 'Color', app.CallingApp.General.Colors(2,:));
%             plot(app.UIAxes2, xData, rawData.statsData(:, 4), 'Tag', 'Drive-test: MaxHold',  'Color', app.CallingApp.General.Colors(3,:));
%             hold(app.UIAxes2, 'off')
% 
% %             set(app.UIAxes2, 'Interactions', [])
% 
% 
%             % histogramAxes
%             hold(app.UIAxes3, 'on')
%             histogram(app.UIAxes3, rawData.Data{2}, NumBins=64, Normalization='probability', EdgeColor=[.93, .69, .13])
%             app.UIAxes3.YAxis.Exponent = -2;
% 
%             hold(app.UIAxes3, 'off')
%             
% %             set(app.UIAxes3, 'Interactions', [])
%             drawnow nocallbacks
% 
% 
%             % ROI no eixo do espectro...
%             roi = images.roi.Rectangle(app.UIAxes2, Position = [mean([xData(1), xData(end)]), app.UIAxes2.YLim(1), 1, diff(app.UIAxes2.YLim)], ...
%                                                     Color = [0.40,0.73,0.88], MarkerSize = 4, Deletable = 0, LineWidth = .5, Tag='Drive-test: ROI');
%             addlistener(roi,'MovingROI', @app.ROIEvents);
%             addlistener(roi,'ROIMoved',  @app.ROIEvents);
% 
% 
%             % Leitura de dados de teste...
%             Drive = Argus_DriveTest('Energia_b.csv');
%             
%             hold(app.UIAxes1, 'on')
%             geodensityplot(app.UIAxes1, Drive.Latitude, Drive.Longitude, Drive.Level, 'FaceColor', 'interp');
%             geoplot(app.UIAxes1, Drive.Latitude, Drive.Longitude)
%             
% %             idx = find(Drive.Level > max(Drive.Level));
% %             geoscatter(app.UIAxes1, Drive.Latitude(idx), Drive.Longitude(idx), Drive.Level(idx), 'filled', Marker='hexagram')
%             hold(app.UIAxes1, 'off')
% 
% 
%             % Visibilidade...
%             while ~isequal(app.UIAxes1.InnerPosition, [ 44,  34, 830, 574])       
%                 pause(5)
%                 app.UIAxes1.InnerPosition = [ 44,  34, 830, 574];
%                 drawnow nocallbacks
%             end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin2 = [];
            delete(app)
            
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            
            app.NodeOrder_Down.Enable = 0;
            app.NodeOrder_Up.Enable   = 0;

            selectedNodes = app.Tree.SelectedNodes;
            
            switch selectedNodes.NodeData
                case 1; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1, 93,   0,  0, 0, 1, 24};
                case 2; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1,  0, 142,  0, 0, 1, 24};
                case 3; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1,  0,   0, 44, 0, 1, 24};
                
                otherwise
                    if selectedNodes.NodeData == 4
                        app.Tree.SelectedNodes = app.Tree.Children(4).Children(1);
                    end
                    app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1, 0, 0, 0, 115, 1, 24};

                    app.NodeOrder_Down.Enable = 1;
                    app.NodeOrder_Up.Enable   = 1;
            end

            expand(app.Tree, 'all')
            
        end

        % Callback function
        function BinValueValueChanged(app, event)
            
            if strcmp(app.BinValue.Value, 'Percentil')
                app.PercentilFactor.Enable      = 1;
                app.PercentilFactorLabel.Enable = 1;
            else
                app.PercentilFactor.Enable      = 0;
                app.PercentilFactorLabel.Enable = 0;
            end

        end

        % Callback function
        function TipoDropDownValueChanged(app, event)
            
            geobasemap(app.UIAxes1, app.TipoDropDown.Value)

        end

        % Callback function
        function Button_2Pushed(app, event)
            
            app.UIAxes1.InnerPosition = [ 44,  34, 830, 574];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_2, 'Frequência (MHz)')
            ylabel(app.UIAxes2_2, 'Nível (dBm)')
            app.GridLayout22 = uigridlayout(app.UIFigure);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.UIAxes3 = uiaxes(app.GridLayout23);
            xlabel(app.UIAxes3, 'Amostra')
            ylabel(app.UIAxes3, 'Densidade (dBm/Hz)')
            app.TabGroup = uitabgroup(app.GridLayout23);
            app.GERALTab = uitab(app.TabGroup);
            app.GERALTab.Title = 'GERAL';
            app.GridLayout26 = uigridlayout(app.GERALTab);
            app.Tree2 = uitree(app.GridLayout26, 'checkbox');
            app.arquivo1binNode = uitreenode(app.Tree2);
            app.arquivo1binNode.Text = 'arquivo1.bin';
            app.arquivo2binNode = uitreenode(app.Tree2);
            app.arquivo2binNode.Text = 'arquivo2.bin';
            % Assign Checked Nodes
            app.Tree2.CheckedNodes = [app.arquivo1binNode];
            app.ArquivosrelacionadosLabel = uilabel(app.GridLayout26);
            app.ArquivosrelacionadosLabel.Text = 'Arquivo(s) relacionado(s):';
            app.OutrasinformaesTextAreaLabel = uilabel(app.GridLayout26);
            app.OutrasinformaesTextAreaLabel.Text = 'Outras informações:';
            app.OutrasinformaesTextArea = uitextarea(app.GridLayout26);
            app.OutrasinformaesTextArea.Editable = 'off';
            app.OutrasinformaesTextArea.Value = {'Faixa: 108.000 - 137.000 MHz'; ''; 'Pontos por traço: 4561'; 'Unidade: dBm'; 'Resolução: 100.000 kHz'; ''; 'Arquivo(s):'; '• arquivo1.bin'; '  Período: X a Y'; '  Varreduras: 300'; '  Pontos de monitoração: 2110'; '  Tempo médio de revisita: 320 ms'; '  Limites: -105.3 a -20.2 dBm'; ''; '• arquivo2.bin'; '  Período: X a Y'; '  Varreduras: 300'; '  Pontos de monitoração: 2110'; '  Tempo médio de revisita: 320 ms'; '  Limites: -105.3 a -20.2 dBm'};
            app.GridLayout28 = uigridlayout(app.GridLayout26);
            app.EmissoDropDownLabel = uilabel(app.GridLayout28);
            app.EmissoDropDownLabel.Text = 'Emissão:';
            app.Image = uiimage(app.GridLayout28);
            app.Image.ImageSource = 'LT_refresh.png';
            app.EmissoDropDown = uidropdown(app.GridLayout26);
            app.EmissoDropDown.Items = {};
            app.EmissoDropDown.Value = {};
            app.PLOTTab = uitab(app.TabGroup);
            app.PLOTTab.Title = 'PLOT';
            app.GridLayout24 = uigridlayout(app.PLOTTab);
            app.Tree = uitree(app.GridLayout24);
            app.AspectosgeraisNode = uitreenode(app.Tree);
            app.AspectosgeraisNode.Icon = 'LT_settings.png';
            app.AspectosgeraisNode.Text = 'Aspectos gerais';
            app.RotaNode = uitreenode(app.Tree);
            app.RotaNode.Icon = 'play_View.png';
            app.RotaNode.Text = 'Rota';
            app.ScatterNode = uitreenode(app.Tree);
            app.ScatterNode.Icon = 'play_View.png';
            app.ScatterNode.Text = 'Scatter';
            app.DensidadeNode = uitreenode(app.Tree);
            app.DensidadeNode.Icon = 'play_Unview.png';
            app.DensidadeNode.Text = 'Densidade';
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.AlgoritmoDropDownLabel = uilabel(app.GridLayout25);
            app.AlgoritmoDropDownLabel.Text = 'Algoritmo:';
            app.AlgoritmoDropDown = uidropdown(app.GridLayout25);
            app.AlgoritmoDropDown.Items = {'Rectangular grid', 'Map matching'};
            app.AlgoritmoDropDown.Value = 'Rectangular grid';
            app.GridLayout27 = uigridlayout(app.GridLayout23);
            app.Button3 = uibutton(app.GridLayout27, 'push');
            app.Button3.Icon = 'DT_play.png';
            app.Button3.Text = 'Executar';
            app.Button = uibutton(app.GridLayout27, 'push');
            app.Button.Icon = 'LT_info.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end