classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        Button2           matlab.ui.control.Button
        Info              matlab.ui.control.TextArea
        GridLayout        matlab.ui.container.GridLayout
        InfoLabel         matlab.ui.control.Label
        Warn              matlab.ui.control.Image
        VariablesLabel    matlab.ui.control.Label
        Toolbar1          matlab.ui.control.Button
        Toolbar2          matlab.ui.control.Button
        Toolbar3          matlab.ui.control.Button
        Button1           matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        Grid2             matlab.ui.container.GridLayout
        Variables_Label1  matlab.ui.control.Label
        Variables_Label2  matlab.ui.control.Label
        Variables_Label3  matlab.ui.control.Label
        CheckBox7         matlab.ui.control.CheckBox
        CheckBox6         matlab.ui.control.CheckBox
        CheckBox5         matlab.ui.control.CheckBox
        CheckBox4         matlab.ui.control.CheckBox
        CheckBox3         matlab.ui.control.CheckBox
        CheckBox2         matlab.ui.control.CheckBox
        CheckBox1         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        
        CallingApp
        
        newVersion
        PublicLinks
        
    end
    
    methods (Access = private)
        
        function Fcn1_VersionInfo(app)
            
            [MatlabVersion, ReleaseDate] = version;
            MatlabRoot                   = matlabroot;
            InstalledProducts            = ver;
            
            MatlabProducts = {};
            for ii = 1:numel(InstalledProducts)
                MatlabProducts = [MatlabProducts; sprintf('%s v. %s', InstalledProducts(ii).Name, InstalledProducts(ii).Version)];
            end
            
            Python = pyenv;
                        
            app.Info.Value = sprintf(['Informações acerca das versões instaladas dos módulos do appAnálise:\n• appAnalise v. %s\n'                           ...
                                      '• fiscaliza v. %s (Integração appAnálise/Fiscaliza)\n' ...
                                      '• Base de dados offline da Agência atualizada no dia %s (anateldb)\n\n' ...
                                      'appAnalise\n• %s\n\n' ...
                                      'Matlab\n• v. %s (Release date: %s)\n• %s\n• %s\n\n' ...
                                      'Python\n• v. %s\n• %s\n• %s\n• %s'],                ...                                      
                                      app.CallingApp.Version.appAnalise, ...
                                      app.CallingApp.Version.fiscaliza,  ...
                                      app.CallingApp.Version.anateldb,   ...
                                      app.CallingApp.RootFolder,         ...
                                      MatlabVersion,                     ...
                                      ReleaseDate,                       ...
                                      strjoin(MatlabProducts, ', '),     ...
                                      MatlabRoot,     ...
                                      Python.Version, ...
                                      Python.Home,    ...
                                      Python.Library, ...
                                      Python.Executable);
            
            app.Warn.Visible   = 0;
            
            app.Button2.Enable  = 1;
            app.Toolbar2.Enable = 0;
            app.Toolbar3.Enable = 0;
            
        end
        
        
        function Fcn2_PublicLinks(app)
            
            fileID = fopen(fullfile(app.CallingApp.RootFolder, 'DataBase', 'PublicLinks.cfg'), 'rt');
            app.PublicLinks = jsondecode(fread(fileID, [1, Inf], '*char'));
            fclose(fileID);            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            if isdeployed
                set(app.Grid2.Children, 'Enable', 0)
                app.Button1.Enable = 0;
            end
                        
            Fcn1_VersionInfo(app)
            Fcn2_PublicLinks(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5, CheckBox6, CheckBox7
        function CheckBoxes(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value || app.CheckBox7.Value
                app.Button1.Enable = 1;
            else
                app.Button1.Enable = 0;
            end
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'Samples', app.CallingApp.Samples)
                openvar('Samples')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'traceInfo', app.CallingApp.traceInfo)
                openvar('traceInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
            if app.CheckBox7.Value
                assignin('base', 'reportInfo', app.CallingApp.reportInfo)
                openvar('reportInfo')
            end
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            try
                FileVersion = jsondecode(webread(app.PublicLinks.FileVersion));
                app.newVersion = struct('appAnalise', FileVersion.appAnalise.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version,  ...
                                        'anateldb',   FileVersion.anateldb.ReleaseDate);
                
                app.Info.Value{end+1} = sprintf(['\nInformações acerca das últimas versões dos módulos do appAnálise:\n' ...
                                                 '• appAnalise v. %s (Release date: %s)\n'                           ...
                                                 '• fiscaliza v. %s (Release date: %s)\n'                            ...
                                                 '• anateldb v. %s (Release date: %s)\n'],                           ...
                                                 FileVersion.appAnalise.Version, FileVersion.appAnalise.ReleaseDate, ...
                                                 FileVersion.fiscaliza.Version,  FileVersion.fiscaliza.ReleaseDate,  ...
                                                 FileVersion.anateldb.Version,   FileVersion.anateldb.ReleaseDate);
                
                if isequal(app.CallingApp.Version, app.newVersion)
                    app.Warn.Visible = 0;
                    uialert(app.UIFigure, sprintf('Módulos atualizados:\n• appAnalise, fiscaliza, anateldb'), 'appAnálise', 'Icon', 'success')
                    
                else
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.Version.appAnalise, app.newVersion.appAnalise); str1(end+1) = {'appAnalise'};
                    else;                                                                    str2(end+1) = {'appAnalise'};
                    end                    
                    if strcmp(app.CallingApp.Version.fiscaliza,  app.newVersion.fiscaliza);  str1(end+1) = {'fiscaliza'};
                    else;                                                                    str2(end+1) = {'fiscaliza'}; app.Toolbar2.Enable = 1;
                    end                    
                    if strcmp(app.CallingApp.Version.anateldb,   app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                                    str2(end+1) = {'anateldb'};  app.Toolbar3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) desatualizado(s):\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) desatualizado(s):\n• %s', strjoin(str2, ', '));
                    end                    
                    uialert(app.UIFigure, msg, 'appAnálise', 'Icon', 'warning')
                    
                    set(app.Warn, 'Visible', 1, 'Tooltip', strjoin(str2, ', '))
                end
                app.Button2.Enable = 0;
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end
            
        end

        % Button pushed function: Toolbar1
        function Toolbar1Pushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            if file
                try
                pyenv('Version', fullfile(pathfile, file));
                uialert(app.UIFigure, 'Alteração realizada com sucesso!', 'appAnálise', 'Icon', 'success')
                Fcn1_VersionInfo(app)
                
                catch ME
                    uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
                end
            end
            
        end

        % Button pushed function: Toolbar2
        function Toolbar2Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a biblioteca Fiscaliza...');
            
            Python   = pyenv;
            [~, msg] = system(fullfile(Python.Home, 'Scripts', 'pip install fiscaliza -U'));
            
            app.Info.Value{end+1} = sprintf('\n%s', msg);
            
            [~, fiscalizaVersion] = system(fullfile(Python.Home, 'Scripts', 'pip list | findstr fiscaliza'));
            if ~isempty(fiscalizaVersion); app.CallingApp.Version.fiscaliza = strtrim(extractAfter(fiscalizaVersion, 'fiscaliza'));
            end
            
        end

        % Button pushed function: Toolbar3
        function Toolbar3Pushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a base de dados offline da Agência...');
                
                websave(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.xlsx'), app.PublicLinks.AnatelDB)
                
                delete(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.mat'))
                clear global AnatelDB
                clear global AnatelDB_ExtractDate
                drawnow nocallbacks
                
                Misc_DataBaseRead(app.CallingApp.RootFolder)
                
                global AnatelDB_ExtractDate
                app.Version.anateldb = AnatelDB_ExtractDate;
                
                Fcn1_VersionInfo(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox1.Text = 'metaData';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox2.Text = 'Samples';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox3.Text = 'specData';
            app.CheckBox3.Value = true;
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox4.Text = 'traceInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox5.Text = 'peaksTable';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox6.Text = 'exceptionList';
            app.CheckBox7 = uicheckbox(app.Grid2);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox7.Text = 'reportInfo';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas ao "Relatório":';
            app.Variables_Label2 = uilabel(app.Grid2);
            app.Variables_Label2.Text = 'Relacionadas ao "Playback":';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas à leitura dos arquivos:';
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'White_Play.png';
            app.Button1.Text = 'Explorar dados';
            app.Toolbar3 = uibutton(app.Grid1, 'push');
            app.Toolbar3.ButtonPushedFcn = createCallbackFcn(app, @Toolbar3Pushed, true);
            app.Toolbar3.Icon = 'Settings_Sheet.png';
            app.Toolbar3.Tooltip = {'Atualizar base de dados offline'};
            app.Toolbar3.Text = '';
            app.Toolbar2 = uibutton(app.Grid1, 'push');
            app.Toolbar2.ButtonPushedFcn = createCallbackFcn(app, @Toolbar2Pushed, true);
            app.Toolbar2.Icon = 'Report_Icon6.png';
            app.Toolbar2.Tooltip = {'Atualizar biblioteca Fiscaliza'};
            app.Toolbar2.Text = '';
            app.Toolbar1 = uibutton(app.Grid1, 'push');
            app.Toolbar1.ButtonPushedFcn = createCallbackFcn(app, @Toolbar1Pushed, true);
            app.Toolbar1.Icon = 'Settings_Python.png';
            app.Toolbar1.Tooltip = {'Editar ambiente Python'};
            app.Toolbar1.Text = '';
            app.VariablesLabel = uilabel(app.Grid1);
            app.VariablesLabel.Text = 'Visualização das principais variáveis do appAnálise';
            app.GridLayout = uigridlayout(app.Grid1);
            app.Warn = uiimage(app.GridLayout);
            app.Warn.ImageSource = 'Warn_Yellow.png';
            app.InfoLabel = uilabel(app.GridLayout);
            app.InfoLabel.Text = 'Informações gerais';
            app.Info = uitextarea(app.Grid1);
            app.Info.Value = {'General info...'};
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Reload.png';
            app.Button2.Text = 'Verificar versões';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end